classdef Vibrations_Lab_GUI_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RefreshsettingsButton         matlab.ui.control.Button
        LightsButtonGroup             matlab.ui.container.ButtonGroup
        OnButton                      matlab.ui.control.RadioButton
        OnwithstrobingButton          matlab.ui.control.RadioButton
        OffButton                     matlab.ui.control.RadioButton
        ExternalspeakerIDEditField    matlab.ui.control.NumericEditField
        ExternalspeakerIDEditFieldLabel  matlab.ui.control.Label
        VibrationsKitUILabel          matlab.ui.control.Label
        DrivingAmplitudeSlider        matlab.ui.control.Slider
        DrivingAmplitudeSliderLabel   matlab.ui.control.Label
        StrobingfrequencyButtonGroup  matlab.ui.container.ButtonGroup
        StrobingfrequencyHzEditField  matlab.ui.control.NumericEditField
        StrobingfrequencyHzLabel      matlab.ui.control.Label
        Detuned3HzButton              matlab.ui.control.RadioButton
        Detuned2HzButton              matlab.ui.control.RadioButton
        UserdefinedfrequencyButton    matlab.ui.control.RadioButton
        Detuned1HzButton              matlab.ui.control.RadioButton
        SynchronizedButton            matlab.ui.control.RadioButton
        TurnoffsystemButton           matlab.ui.control.Button
        DrivingfrequencyHzEditField   matlab.ui.control.NumericEditField
        DrivingfrequencyHzLabel       matlab.ui.control.Label
        TurnonsystemButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        A_strobe;
        f_strobe;
        A_drive;
        f_drive;
        done=0;
        stop1;
        player;
        refresh=0;
        % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TurnonsystemButton
        function TurnonsystemButtonPushed(app, event)
            app.refresh=1;
            Fs =40000;
            T=1/Fs;
            runtime=100;
            a1=app.ExternalspeakerIDEditField.Value;
            t=0:T:runtime;
            app.stop1=0;
            while app.stop1==0
                app.f_drive = app.DrivingfrequencyHzEditField.Value;
                app.A_drive=app.DrivingAmplitudeSlider.Value;
                if(app.OffButton.Value)
                    app.A_strobe=0;
                    y=app.A_strobe.*ones([1,length(t)]);
                end
                if(app.OnButton.Value)
                    app.A_strobe=10;
                    y=app.A_strobe.*sin(2*pi*1000*t);
                end
                if(app.SynchronizedButton.Value && app.OnwithstrobingButton.Value)
                    app.A_strobe=10;
                    app.f_strobe=app.f_drive;
                    omega2 = 2*pi*app.f_strobe;
                    y=app.A_strobe.*((square(omega2*t,20)+1)./2);
                end
                if(app.Detuned1HzButton.Value && app.OnwithstrobingButton.Value)
                    app.A_strobe=10;
                    app.f_strobe=app.f_drive+1;
                    omega2 = 2*pi*app.f_strobe;
                    y=app.A_strobe.*((square(omega2*t,20)+1)./2);
                end
                if(app.UserdefinedfrequencyButton.Value && app.OnwithstrobingButton.Value)
                    app.A_strobe=10;
                    app.f_strobe=app.StrobingfrequencyHzEditField.Value;
                    omega2 = 2*pi*app.f_strobe;
                    y=app.A_strobe.*((square(omega2*t,20)+1)./2);
                end
                if(app.Detuned2HzButton.Value && app.OnwithstrobingButton.Value)
                    app.A_strobe=10;
                    app.f_strobe=app.f_drive+2;
                    omega2 = 2*pi*app.f_strobe;
                    y=app.A_strobe.*((square(omega2*t,20)+1)./2);
                end
                if(app.Detuned3HzButton.Value && app.OnwithstrobingButton.Value)
                    app.A_strobe=10;
                    app.f_strobe=app.f_drive+3;
                    omega2 = 2*pi*app.f_strobe;
                    y=app.A_strobe.*((square(omega2*t,20)+1)./2);
                end
                omega1 = 2*pi*app.f_drive;
                x=app.A_drive*square(omega1*t);
                Out = [x; y];
                app.player = audioplayer(Out, Fs, 16, a1);
                play(app.player)
                pause(runtime)
                app.stop1=1; 
            end
        end

        % Button pushed function: TurnoffsystemButton
        function TurnoffsystemButtonPushed(app, event)
            stop(app.player);
            app.done=1; 
            app.refresh=0;
        end

        % Button pushed function: RefreshsettingsButton
        function RefreshsettingsButtonPushed(app, event)
        if app.refresh==1
            app.refresh=1;
            Fs =40000;
            T=1/Fs;
            runtime=100;
            a1=app.ExternalspeakerIDEditField.Value;
            t=0:T:runtime;
            app.done=0;
            app.stop1=app.done;
            while app.stop1==0
                app.f_drive = app.DrivingfrequencyHzEditField.Value;
                app.A_drive=app.DrivingAmplitudeSlider.Value;
                if(app.OffButton.Value)
                    app.A_strobe=0;
                    y=app.A_strobe.*t;
                end
                if(app.OnButton.Value)
                    app.A_strobe=10;
                    y=app.A_strobe.*sin(2*pi*1000*t);
                end
                if(app.SynchronizedButton.Value && app.OnwithstrobingButton.Value)
                    app.A_strobe=10;
                    app.f_strobe=app.f_drive;
                    omega2 = 2*pi*app.f_strobe;
                    y=app.A_strobe.*((square(omega2*t,20)+1)./2);
                end
                if(app.Detuned1HzButton.Value && app.OnwithstrobingButton.Value)
                    app.A_strobe=10;
                    app.f_strobe=app.f_drive+1;
                    omega2 = 2*pi*app.f_strobe;
                    y=app.A_strobe.*((square(omega2*t,20)+1)./2);
                end
                if(app.UserdefinedfrequencyButton.Value && app.OnwithstrobingButton.Value)
                    app.A_strobe=10;
                    app.f_strobe=app.StrobingfrequencyHzEditField.Value;
                    omega2 = 2*pi*app.f_strobe;
                    y=app.A_strobe.*((square(omega2*t,20)+1)./2);
                end
                if(app.Detuned2HzButton.Value && app.OnwithstrobingButton.Value)
                    app.A_strobe=10;
                    app.f_strobe=app.f_drive+2;
                    omega2 = 2*pi*app.f_strobe;
                    y=app.A_strobe.*((square(omega2*t,20)+1)./2);
                end
                if(app.Detuned3HzButton.Value && app.OnwithstrobingButton.Value)
                    app.A_strobe=10;
                    app.f_strobe=app.f_drive+3;
                    omega2 = 2*pi*app.f_strobe;
                    y=app.A_strobe.*((square(omega2*t,20)+1)./2);
                end
                omega1 = 2*pi*app.f_drive;
                x=app.A_drive*square(omega1*t);
                Out = [x; y];
                stop(app.player)
                app.player = audioplayer(Out, Fs, 16, a1);
                play(app.player)
                pause(runtime) 
                app.stop1=1;
            end
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TurnonsystemButton = uibutton(app.UIFigure, 'push');
            app.TurnonsystemButton.ButtonPushedFcn = createCallbackFcn(app, @TurnonsystemButtonPushed, true);
            app.TurnonsystemButton.Text = 'Turn on system';
            app.DrivingfrequencyHzLabel = uilabel(app.UIFigure);
            app.DrivingfrequencyHzLabel.Text = 'Driving frequency (Hz)';
            app.DrivingfrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.DrivingfrequencyHzEditField.Limits = [0 1000];
            app.TurnoffsystemButton = uibutton(app.UIFigure, 'push');
            app.TurnoffsystemButton.ButtonPushedFcn = createCallbackFcn(app, @TurnoffsystemButtonPushed, true);
            app.TurnoffsystemButton.Text = 'Turn off system';
            app.StrobingfrequencyButtonGroup = uibuttongroup(app.UIFigure);
            app.StrobingfrequencyButtonGroup.Title = 'Strobing frequency';
            app.SynchronizedButton = uiradiobutton(app.StrobingfrequencyButtonGroup);
            app.SynchronizedButton.Text = 'Synchronized';
            app.SynchronizedButton.Value = true;
            app.Detuned1HzButton = uiradiobutton(app.StrobingfrequencyButtonGroup);
            app.Detuned1HzButton.Text = 'Detuned +1 Hz';
            app.UserdefinedfrequencyButton = uiradiobutton(app.StrobingfrequencyButtonGroup);
            app.UserdefinedfrequencyButton.Text = 'User defined frequency';
            app.Detuned2HzButton = uiradiobutton(app.StrobingfrequencyButtonGroup);
            app.Detuned2HzButton.Text = 'Detuned +2 Hz';
            app.Detuned3HzButton = uiradiobutton(app.StrobingfrequencyButtonGroup);
            app.Detuned3HzButton.Text = 'Detuned +3 Hz';
            app.StrobingfrequencyHzLabel = uilabel(app.StrobingfrequencyButtonGroup);
            app.StrobingfrequencyHzLabel.Text = 'Strobing frequency (Hz)';
            app.StrobingfrequencyHzEditField = uieditfield(app.StrobingfrequencyButtonGroup, 'numeric');
            app.StrobingfrequencyHzEditField.Limits = [0 1000];
            app.DrivingAmplitudeSliderLabel = uilabel(app.UIFigure);
            app.DrivingAmplitudeSliderLabel.Text = 'Driving Amplitude';
            app.DrivingAmplitudeSlider = uislider(app.UIFigure);
            app.DrivingAmplitudeSlider.Limits = [0 0.2];
            app.VibrationsKitUILabel = uilabel(app.UIFigure);
            app.VibrationsKitUILabel.Text = 'Vibrations Kit UI';
            app.ExternalspeakerIDEditFieldLabel = uilabel(app.UIFigure);
            app.ExternalspeakerIDEditFieldLabel.Text = 'External speaker ID';
            app.ExternalspeakerIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExternalspeakerIDEditField.Limits = [0 150];
            app.ExternalspeakerIDEditField.Value = 3;
            app.LightsButtonGroup = uibuttongroup(app.UIFigure);
            app.LightsButtonGroup.Title = 'Lights';
            app.OffButton = uiradiobutton(app.LightsButtonGroup);
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.OnwithstrobingButton = uiradiobutton(app.LightsButtonGroup);
            app.OnwithstrobingButton.Text = 'On with strobing';
            app.OnButton = uiradiobutton(app.LightsButtonGroup);
            app.OnButton.Text = 'On';
            app.RefreshsettingsButton = uibutton(app.UIFigure, 'push');
            app.RefreshsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshsettingsButtonPushed, true);
            app.RefreshsettingsButton.Text = 'Refresh settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vibrations_Lab_GUI_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end